classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageSegmentationTab          matlab.ui.container.Tab
        LowerThresholdEditField       matlab.ui.control.NumericEditField
        LowerThresholdEditFieldLabel  matlab.ui.control.Label
        SigmaEditField                matlab.ui.control.NumericEditField
        SigmaEditFieldLabel           matlab.ui.control.Label
        UpperThresholdEditField       matlab.ui.control.NumericEditField
        UpperThresholdEditFieldLabel  matlab.ui.control.Label
        RadiusEditField               matlab.ui.control.NumericEditField
        RadiusEditFieldLabel          matlab.ui.control.Label
        EdgeOperatorDropDown          matlab.ui.control.DropDown
        EdgeOperatorDropDownLabel     matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        InsertImageButton             matlab.ui.control.Button
        SegmentedImageLabel           matlab.ui.control.Label
        EdgeImageLabel                matlab.ui.control.Label
        OriginalImageLabel            matlab.ui.control.Label
        SegmentedImage                matlab.ui.control.Image
        EdgeImage                     matlab.ui.control.Image
        OriginalImage                 matlab.ui.control.Image
        HoughTransformationTab        matlab.ui.container.Tab
        EdgeOperatorDropDown_2        matlab.ui.control.DropDown
        EdgeOperatorDropDown_2Label   matlab.ui.control.Label
        ProcessButton_2               matlab.ui.control.Button
        InsertImageButton_2           matlab.ui.control.Button
        TransformedImageLabel         matlab.ui.control.Label
        OriginalImageLabel_2          matlab.ui.control.Label
        TransformedImage              matlab.ui.control.Image
        OriginalImage_2               matlab.ui.control.Image
    end

    
    properties (Access = private)
        originalImage
    end
    
    methods (Access = public)
        
        function InputImageEvent(app, imageContainer)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.tif;*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.tif, *.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            app.originalImage = imread(filepath); 
            
            % Reset all UIs
            app.OriginalImage.ImageSource = '';
            app.EdgeImage.ImageSource = '';
            app.SegmentedImage.ImageSource = '';

            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp') || strcmpi(ext, '.tif')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                
                % Use tempname to generate a complete file path
                tempfile = [tempname, '.png'];  % Correctly append '.png' to tempname
                
                % Write the image to the temporary file
                imwrite(img, tempfile);
                
                % Update the Image component's ImageSource property
                imageContainer.ImageSource = tempfile;  % Use the complete file path
            else
                % Use the selected file directly if it's already a supported format
                imageContainer.ImageSource = filepath;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertImageButton
        function InsertImageButtonPushed(app, event)
            app.InputImageEvent(app.OriginalImage);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            edgeImage = detectEdge(app.originalImage, app.EdgeOperatorDropDown.Value);
            rgbEdgeImage = uint8(cat(3, edgeImage, edgeImage, edgeImage));

            if(app.EdgeOperatorDropDown.Value == "Canny")
                rgbEdgeImage = rgbEdgeImage * 255;
            end
            
            app.EdgeImage.ImageSource = rgbEdgeImage;
        end

        % Button pushed function: InsertImageButton_2
        function InsertImageButton_2Pushed(app, event)
            app.InputImageEvent(app.OriginalImage_2);
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            grayImage = im2gray(app.originalImage);
            filteredImage = imgaussfilt(grayImage, 1, 'FilterSize', 3);
            %figure; imshow(filteredImage);
            edgeImage = detectEdge(filteredImage, app.EdgeOperatorDropDown_2.Value);

            houghImage = houghTransformation(app.originalImage, edgeImage);
            app.TransformedImage.ImageSource = houghImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageSegmentationTab = uitab(app.TabGroup);
            app.ImageSegmentationTab.Title = 'Image Segmentation';
            app.OriginalImage = uiimage(app.ImageSegmentationTab);
            app.EdgeImage = uiimage(app.ImageSegmentationTab);
            app.SegmentedImage = uiimage(app.ImageSegmentationTab);
            app.OriginalImageLabel = uilabel(app.ImageSegmentationTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.EdgeImageLabel = uilabel(app.ImageSegmentationTab);
            app.EdgeImageLabel.Text = 'Edge Image';
            app.SegmentedImageLabel = uilabel(app.ImageSegmentationTab);
            app.SegmentedImageLabel.Text = 'Segmented Image';
            app.InsertImageButton = uibutton(app.ImageSegmentationTab, 'push');
            app.InsertImageButton.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButtonPushed, true);
            app.InsertImageButton.Text = 'Insert Image';
            app.ProcessButton = uibutton(app.ImageSegmentationTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.EdgeOperatorDropDownLabel = uilabel(app.ImageSegmentationTab);
            app.EdgeOperatorDropDownLabel.Text = 'Edge Operator';
            app.EdgeOperatorDropDown = uidropdown(app.ImageSegmentationTab);
            app.EdgeOperatorDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeOperatorDropDown.Value = 'Laplace';
            app.RadiusEditFieldLabel = uilabel(app.ImageSegmentationTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ImageSegmentationTab, 'numeric');
            app.RadiusEditField.Limits = [0 Inf];
            app.UpperThresholdEditFieldLabel = uilabel(app.ImageSegmentationTab);
            app.UpperThresholdEditFieldLabel.Text = 'Upper Threshold';
            app.UpperThresholdEditField = uieditfield(app.ImageSegmentationTab, 'numeric');
            app.UpperThresholdEditField.Limits = [0 Inf];
            app.SigmaEditFieldLabel = uilabel(app.ImageSegmentationTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.ImageSegmentationTab, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.LowerThresholdEditFieldLabel = uilabel(app.ImageSegmentationTab);
            app.LowerThresholdEditFieldLabel.Text = 'Lower Threshold';
            app.LowerThresholdEditField = uieditfield(app.ImageSegmentationTab, 'numeric');
            app.LowerThresholdEditField.Limits = [0 Inf];
            app.HoughTransformationTab = uitab(app.TabGroup);
            app.HoughTransformationTab.Title = 'Hough Transformation';
            app.OriginalImage_2 = uiimage(app.HoughTransformationTab);
            app.TransformedImage = uiimage(app.HoughTransformationTab);
            app.OriginalImageLabel_2 = uilabel(app.HoughTransformationTab);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.TransformedImageLabel = uilabel(app.HoughTransformationTab);
            app.TransformedImageLabel.Text = 'Transformed Image';
            app.InsertImageButton_2 = uibutton(app.HoughTransformationTab, 'push');
            app.InsertImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertImageButton_2Pushed, true);
            app.InsertImageButton_2.Text = 'Insert Image';
            app.ProcessButton_2 = uibutton(app.HoughTransformationTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.EdgeOperatorDropDown_2Label = uilabel(app.HoughTransformationTab);
            app.EdgeOperatorDropDown_2Label.Text = 'Edge Operator';
            app.EdgeOperatorDropDown_2 = uidropdown(app.HoughTransformationTab);
            app.EdgeOperatorDropDown_2.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeOperatorDropDown_2.Value = 'Laplace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end